classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DesignedbyTheEngineerLabel  matlab.ui.control.Label
        TabGroup2                   matlab.ui.container.TabGroup
        ConfigurationTab            matlab.ui.container.Tab
        ClearScreenButton           matlab.ui.control.Button
        Link4BlurSpinner_3          matlab.ui.control.Spinner
        Link2BlurSpinnerLabel_4     matlab.ui.control.Label
        Length1Spinner              matlab.ui.control.Spinner
        Length1SpinnerLabel         matlab.ui.control.Label
        Link1BlurSpinnerLabel_3     matlab.ui.control.Label
        Link2BlurSpinnerLabel_3     matlab.ui.control.Label
        Length2Spinner              matlab.ui.control.Spinner
        Length2SpinnerLabel         matlab.ui.control.Label
        Link3BlurSpinner_2          matlab.ui.control.Spinner
        Link4BlurSpinner_2          matlab.ui.control.Spinner
        ReferenceFrameCheckBox      matlab.ui.control.CheckBox
        SamplesSpinner              matlab.ui.control.Spinner
        SamplesSpinnerLabel         matlab.ui.control.Label
        Link2BlurSpinnerLabel_2     matlab.ui.control.Label
        Link1BlurSpinnerLabel_2     matlab.ui.control.Label
        Link4BlurSpinner            matlab.ui.control.Spinner
        Link3BlurSpinner            matlab.ui.control.Spinner
        Link2BlurSpinner            matlab.ui.control.Spinner
        Link2BlurSpinnerLabel       matlab.ui.control.Label
        Link1BlurSpinner            matlab.ui.control.Spinner
        Link1BlurSpinnerLabel       matlab.ui.control.Label
        RuntimeSpinner              matlab.ui.control.Spinner
        RuntimeSpinnerLabel         matlab.ui.control.Label
        PVATab                      matlab.ui.container.Tab
        UIAxesAccEE                 matlab.ui.control.UIAxes
        UIAxesVelEE                 matlab.ui.control.UIAxes
        UIAxesPosEE                 matlab.ui.control.UIAxes
        TrajectoryPlanningPanel     matlab.ui.container.Panel
        amaxEditFieldLabel_2        matlab.ui.control.Label
        t_SEditField                matlab.ui.control.NumericEditField
        amaxEditField               matlab.ui.control.NumericEditField
        amaxEditFieldLabel          matlab.ui.control.Label
        vmaxEditField               matlab.ui.control.NumericEditField
        vmaxEditFieldLabel          matlab.ui.control.Label
        pmaxEditField               matlab.ui.control.NumericEditField
        pmaxEditFieldLabel          matlab.ui.control.Label
        az0EditField                matlab.ui.control.NumericEditField
        az0EditFieldLabel           matlab.ui.control.Label
        ay0EditField                matlab.ui.control.NumericEditField
        ay0EditFieldLabel           matlab.ui.control.Label
        ax0EditField                matlab.ui.control.NumericEditField
        ax0EditFieldLabel           matlab.ui.control.Label
        vz0EditField                matlab.ui.control.NumericEditField
        vz0EditFieldLabel           matlab.ui.control.Label
        vy0EditField                matlab.ui.control.NumericEditField
        vy0EditFieldLabel           matlab.ui.control.Label
        vx0EditField                matlab.ui.control.NumericEditField
        vx0EditFieldLabel           matlab.ui.control.Label
        JointPlanningButton         matlab.ui.control.Button
        MotionTypesDropDown         matlab.ui.control.DropDown
        MotionTypesDropDownLabel    matlab.ui.control.Label
        WaypointButton              matlab.ui.control.Button
        EEPlanningButton            matlab.ui.control.Button
        OperationWorkspaceButton    matlab.ui.control.Button
        Pitch_BEditField            matlab.ui.control.NumericEditField
        Pitch_BEditFieldLabel       matlab.ui.control.Label
        pz_BEditField               matlab.ui.control.NumericEditField
        pz_BEditFieldLabel          matlab.ui.control.Label
        py_BEditField               matlab.ui.control.NumericEditField
        py_BEditFieldLabel          matlab.ui.control.Label
        px_BEditField               matlab.ui.control.NumericEditField
        px_BEditFieldLabel          matlab.ui.control.Label
        Pitch_AEditField            matlab.ui.control.NumericEditField
        Pitch_AEditFieldLabel       matlab.ui.control.Label
        pz_AEditField               matlab.ui.control.NumericEditField
        pz_AEditFieldLabel          matlab.ui.control.Label
        py_AEditField               matlab.ui.control.NumericEditField
        py_AEditFieldLabel          matlab.ui.control.Label
        px_AEditField               matlab.ui.control.NumericEditField
        px_AEditFieldLabel          matlab.ui.control.Label
        InverseKinematicsPanel      matlab.ui.container.Panel
        TabGroup3                   matlab.ui.container.TabGroup
        J1Tab                       matlab.ui.container.Tab
        J1LowerLimitEditField       matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel    matlab.ui.control.Label
        J1UpperLimitEditField       matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel    matlab.ui.control.Label
        J2Tab                       matlab.ui.container.Tab
        J2LowerLimitEditField       matlab.ui.control.NumericEditField
        LowerLimitEditField_2Label  matlab.ui.control.Label
        J2UpperLimitEditField       matlab.ui.control.NumericEditField
        UpperLimitEditField_2Label  matlab.ui.control.Label
        J3Tab                       matlab.ui.container.Tab
        J3LowerLimitEditField       matlab.ui.control.NumericEditField
        LowerLimitEditField_3Label  matlab.ui.control.Label
        J3UpperLimitEditField       matlab.ui.control.NumericEditField
        UpperLimitEditField_3Label  matlab.ui.control.Label
        J4Tab                       matlab.ui.container.Tab
        J4LowerLimitEditField       matlab.ui.control.NumericEditField
        LowerLimitEditField_4Label  matlab.ui.control.Label
        J4UpperLimitEditField       matlab.ui.control.NumericEditField
        UpperLimitEditField_4Label  matlab.ui.control.Label
        ClearWaypointButton         matlab.ui.control.Button
        InverseKinematicsButton     matlab.ui.control.Button
        PitchEditField              matlab.ui.control.NumericEditField
        PitchLabel                  matlab.ui.control.Label
        RollEditField               matlab.ui.control.NumericEditField
        RollEditFieldLabel          matlab.ui.control.Label
        pzEditField                 matlab.ui.control.NumericEditField
        pzEditFieldLabel            matlab.ui.control.Label
        pyEditField                 matlab.ui.control.NumericEditField
        pyEditFieldLabel            matlab.ui.control.Label
        pxEditField                 matlab.ui.control.NumericEditField
        pxEditFieldLabel            matlab.ui.control.Label
        DegLabel_7                  matlab.ui.control.Label
        DegLabel_6                  matlab.ui.control.Label
        DegLabel_5                  matlab.ui.control.Label
        YawLabel                    matlab.ui.control.Label
        YawEditField                matlab.ui.control.NumericEditField
        mLabel_3                    matlab.ui.control.Label
        mLabel_2                    matlab.ui.control.Label
        mLabel                      matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        Joint1Tab                   matlab.ui.container.Tab
        UIAxesPosJoint1             matlab.ui.control.UIAxes
        UIAxesAccJoint1             matlab.ui.control.UIAxes
        UIAxesVelJoint1             matlab.ui.control.UIAxes
        Joint2Tab                   matlab.ui.container.Tab
        UIAxesPosJoint2             matlab.ui.control.UIAxes
        UIAxesAccJoint2             matlab.ui.control.UIAxes
        UIAxesVelJoint2             matlab.ui.control.UIAxes
        Joint3Tab                   matlab.ui.container.Tab
        UIAxesPosJoint3             matlab.ui.control.UIAxes
        UIAxesAccJoint3             matlab.ui.control.UIAxes
        UIAxesVelJoint3             matlab.ui.control.UIAxes
        Joint4Tab                   matlab.ui.container.Tab
        UIAxesPosJoint4             matlab.ui.control.UIAxes
        UIAxesAccJoint4             matlab.ui.control.UIAxes
        UIAxesVelJoint4             matlab.ui.control.UIAxes
        ForwardKinematicsPanel      matlab.ui.container.Panel
        TabGroup4                   matlab.ui.container.TabGroup
        T_1_0Tab                    matlab.ui.container.Tab
        PLabel_4                    matlab.ui.control.Label
        RzLabel_4                   matlab.ui.control.Label
        RyLabel_4                   matlab.ui.control.Label
        RxLabel_4                   matlab.ui.control.Label
        T10_44EditField             matlab.ui.control.NumericEditField
        T10_43EditField             matlab.ui.control.NumericEditField
        T10_42EditField             matlab.ui.control.NumericEditField
        T10_41EditField             matlab.ui.control.NumericEditField
        T10_34EditField             matlab.ui.control.NumericEditField
        T10_33EditField             matlab.ui.control.NumericEditField
        T10_32EditField             matlab.ui.control.NumericEditField
        T10_31EditField             matlab.ui.control.NumericEditField
        T10_24EditField             matlab.ui.control.NumericEditField
        T10_23EditField             matlab.ui.control.NumericEditField
        T10_22EditField             matlab.ui.control.NumericEditField
        T10_21EditField             matlab.ui.control.NumericEditField
        T10_13EditField             matlab.ui.control.NumericEditField
        T10_14EditField             matlab.ui.control.NumericEditField
        T10_12EditField             matlab.ui.control.NumericEditField
        T10_11EditField             matlab.ui.control.NumericEditField
        T_2_0Tab                    matlab.ui.container.Tab
        PLabel_3                    matlab.ui.control.Label
        RzLabel_3                   matlab.ui.control.Label
        RyLabel_3                   matlab.ui.control.Label
        RxLabel_3                   matlab.ui.control.Label
        T20_44EditField             matlab.ui.control.NumericEditField
        T20_43EditField             matlab.ui.control.NumericEditField
        T20_42EditField             matlab.ui.control.NumericEditField
        T20_41EditField             matlab.ui.control.NumericEditField
        T20_34EditField             matlab.ui.control.NumericEditField
        T20_33EditField             matlab.ui.control.NumericEditField
        T20_32EditField             matlab.ui.control.NumericEditField
        T20_31EditField             matlab.ui.control.NumericEditField
        T20_24EditField             matlab.ui.control.NumericEditField
        T20_23EditField             matlab.ui.control.NumericEditField
        T20_22EditField             matlab.ui.control.NumericEditField
        T20_21EditField             matlab.ui.control.NumericEditField
        T20_13EditField             matlab.ui.control.NumericEditField
        T20_14EditField             matlab.ui.control.NumericEditField
        T20_12EditField             matlab.ui.control.NumericEditField
        T20_11EditField             matlab.ui.control.NumericEditField
        T_3_0Tab                    matlab.ui.container.Tab
        PLabel_2                    matlab.ui.control.Label
        RzLabel_2                   matlab.ui.control.Label
        RyLabel_2                   matlab.ui.control.Label
        RxLabel_2                   matlab.ui.control.Label
        T30_44EditField             matlab.ui.control.NumericEditField
        T30_43EditField             matlab.ui.control.NumericEditField
        T30_42EditField             matlab.ui.control.NumericEditField
        T30_41EditField             matlab.ui.control.NumericEditField
        T30_34EditField             matlab.ui.control.NumericEditField
        T30_33EditField             matlab.ui.control.NumericEditField
        T30_32EditField             matlab.ui.control.NumericEditField
        T30_31EditField             matlab.ui.control.NumericEditField
        T30_24EditField             matlab.ui.control.NumericEditField
        T30_23EditField             matlab.ui.control.NumericEditField
        T30_22EditField             matlab.ui.control.NumericEditField
        T30_21EditField             matlab.ui.control.NumericEditField
        T30_13EditField             matlab.ui.control.NumericEditField
        T30_14EditField             matlab.ui.control.NumericEditField
        T30_12EditField             matlab.ui.control.NumericEditField
        T30_11EditField             matlab.ui.control.NumericEditField
        T_4_0Tab                    matlab.ui.container.Tab
        PLabel                      matlab.ui.control.Label
        RzLabel                     matlab.ui.control.Label
        RyLabel                     matlab.ui.control.Label
        RxLabel                     matlab.ui.control.Label
        T40_44EditField             matlab.ui.control.NumericEditField
        T40_43EditField             matlab.ui.control.NumericEditField
        T40_42EditField             matlab.ui.control.NumericEditField
        T40_41EditField             matlab.ui.control.NumericEditField
        T40_34EditField             matlab.ui.control.NumericEditField
        T40_33EditField             matlab.ui.control.NumericEditField
        T40_32EditField             matlab.ui.control.NumericEditField
        T40_31EditField             matlab.ui.control.NumericEditField
        T40_24EditField             matlab.ui.control.NumericEditField
        T40_23EditField             matlab.ui.control.NumericEditField
        T40_22EditField             matlab.ui.control.NumericEditField
        T40_21EditField             matlab.ui.control.NumericEditField
        T40_13EditField             matlab.ui.control.NumericEditField
        T40_14EditField             matlab.ui.control.NumericEditField
        T40_12EditField             matlab.ui.control.NumericEditField
        T40_11EditField             matlab.ui.control.NumericEditField
        DHTab                       matlab.ui.container.Tab
        thetaLabel                  matlab.ui.control.Label
        dLabel                      matlab.ui.control.Label
        alphaLabel                  matlab.ui.control.Label
        aLabel                      matlab.ui.control.Label
        T40_44EditField_2           matlab.ui.control.NumericEditField
        T40_43EditField_2           matlab.ui.control.NumericEditField
        T40_42EditField_2           matlab.ui.control.NumericEditField
        T40_41EditField_2           matlab.ui.control.NumericEditField
        T40_34EditField_2           matlab.ui.control.NumericEditField
        T40_33EditField_2           matlab.ui.control.NumericEditField
        T40_32EditField_2           matlab.ui.control.NumericEditField
        T40_31EditField_2           matlab.ui.control.NumericEditField
        T40_24EditField_2           matlab.ui.control.NumericEditField
        T40_23EditField_2           matlab.ui.control.NumericEditField
        T40_22EditField_2           matlab.ui.control.NumericEditField
        T40_21EditField_2           matlab.ui.control.NumericEditField
        T40_13EditField_2           matlab.ui.control.NumericEditField
        T40_14EditField_2           matlab.ui.control.NumericEditField
        T40_12EditField_2           matlab.ui.control.NumericEditField
        T40_11EditField_2           matlab.ui.control.NumericEditField
        ResetButton                 matlab.ui.control.Button
        ForwardKinamaticsButton     matlab.ui.control.Button
        SliderTheta3                matlab.ui.control.Slider
        SliderTheta4                matlab.ui.control.Slider
        SliderTheta2                matlab.ui.control.Slider
        SliderTheta1                matlab.ui.control.Slider
        Theta4EditField             matlab.ui.control.NumericEditField
        Theta4EditFieldLabel        matlab.ui.control.Label
        Theta3EditField             matlab.ui.control.NumericEditField
        Theta3EditFieldLabel        matlab.ui.control.Label
        Theta2EditField             matlab.ui.control.NumericEditField
        Theta2EditFieldLabel        matlab.ui.control.Label
        Theta1EditField             matlab.ui.control.NumericEditField
        Theta1EditFieldLabel        matlab.ui.control.Label
        DegLabel_4                  matlab.ui.control.Label
        DegLabel_3                  matlab.ui.control.Label
        DegLabel_2                  matlab.ui.control.Label
        DegLabel                    matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        theta_A  
        theta_B
        model
        vec
        robot_config
        WorkSpace_Scatter
        way_point
        T_i_0           
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robot_config = struct();
            app.robot_config.robot_length = struct('L1', 0.077,...
                                                   'L2', 0.128,...
                                                   'L3', 0.024,...
                                                   'L4', 0.124,...
                                                   'L5', 0.126);

            app.robot_config.singularity.t = 0;

            app.theta_A = [0, 0, 0, 0];
            app.theta_B = [0, 0, 0, 0];
           
            axis(app.UIAxes, 'equal');
            axis(app.UIAxes, [-0.3 0.4 -0.3 0.3 -0.2 0.5]);            
            view(app.UIAxes, 45, 10);
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');            
            app.robot_config.UIAxes = app.UIAxes; 

            annotation(app.UIFigure, 'textbox', [0.65 0.80 0.20 0.10], ...
            'String', {'\color{red}→ X',...
                       '\color{green}→ Y',...
                       '\color{blue}→ Z'},...
            'FontSize', 10,...
            'FitBoxToText', 'on',...
            'BackgroundColor', 'white');


            % axis(app.UIAxesPosEE, 'equal');
            grid(app.UIAxesPosEE, 'on');
            hold(app.UIAxesPosEE, 'on');
            app.robot_config.UIAxesPosEE = app.UIAxesPosEE;
            % axis(app.UIAxesVelEE, 'equal');
            grid(app.UIAxesVelEE, 'on');
            hold(app.UIAxesVelEE, 'on');
            app.robot_config.UIAxesVelEE = app.UIAxesVelEE;
            % axis(app.UIAxesAccEE, 'equal');
            grid(app.UIAxesAccEE, 'on');
            hold(app.UIAxesAccEE, 'on');
            app.robot_config.UIAxesAccEE = app.UIAxesAccEE;

            % axis(app.UIAxesPosJoint1, 'equal');
            grid(app.UIAxesPosJoint1, 'on');
            hold(app.UIAxesPosJoint1, 'on');
            app.robot_config.UIAxesPosJoint1 = app.UIAxesPosJoint1;
            % axis(app.UIAxesVelJoint1, 'equal');
            grid(app.UIAxesVelJoint1, 'on');
            hold(app.UIAxesVelJoint1, 'on');
            app.robot_config.UIAxesVelJoint1 = app.UIAxesVelJoint1;
            % axis(app.UIAxesAccJoint1, 'equal');
            grid(app.UIAxesAccJoint1, 'on');
            hold(app.UIAxesAccJoint1, 'on');
            app.robot_config.UIAxesAccJoint1 = app.UIAxesAccJoint1;

            % axis(app.UIAxesPosJoint2, 'equal');
            grid(app.UIAxesPosJoint2, 'on');
            hold(app.UIAxesPosJoint2, 'on');
            app.robot_config.UIAxesPosJoint2 = app.UIAxesPosJoint2;
            % axis(app.UIAxesVelJoint2, 'equal');
            grid(app.UIAxesVelJoint2, 'on');
            hold(app.UIAxesVelJoint2, 'on');
            app.robot_config.UIAxesVelJoint2 = app.UIAxesVelJoint2;
            % axis(app.UIAxesAccJoint2, 'equal');
            grid(app.UIAxesAccJoint2, 'on');
            hold(app.UIAxesAccJoint2, 'on');
            app.robot_config.UIAxesAccJoint2 = app.UIAxesAccJoint2;

            % axis(app.UIAxesPosJoint3, 'equal');
            grid(app.UIAxesPosJoint3, 'on');
            hold(app.UIAxesPosJoint3, 'on');
            app.robot_config.UIAxesPosJoint3 = app.UIAxesPosJoint3;
            % axis(app.UIAxesVelJoint3, 'equal');
            grid(app.UIAxesVelJoint3, 'on');
            hold(app.UIAxesVelJoint3, 'on');
            app.robot_config.UIAxesVelJoint3 = app.UIAxesVelJoint3;
            % axis(app.UIAxesAccJoint3, 'equal');
            grid(app.UIAxesAccJoint3, 'on');
            hold(app.UIAxesAccJoint3, 'on');
            app.robot_config.UIAxesAccJoint3 = app.UIAxesAccJoint3;

            % axis(app.UIAxesPosJoint4, 'equal');
            grid(app.UIAxesPosJoint4, 'on');
            hold(app.UIAxesPosJoint4, 'on');
            app.robot_config.UIAxesPosJoint4 = app.UIAxesPosJoint4;
            % axis(app.UIAxesVelJoint4, 'equal');
            grid(app.UIAxesVelJoint4, 'on');
            hold(app.UIAxesVelJoint4, 'on');
            app.robot_config.UIAxesVelJoint4 = app.UIAxesVelJoint4;
            % axis(app.UIAxesAccJoint4, 'equal');
            grid(app.UIAxesAccJoint4, 'on');
            hold(app.UIAxesAccJoint4, 'on');
            app.robot_config.UIAxesAccJoint4 = app.UIAxesAccJoint4;

            app.robot_config.reference_frame = "off";
            handle = InitModel(app.robot_config);
            app.vec = handle.vec;
            app.model = handle.robot;

            if app.ReferenceFrameCheckBox.Value == 1
                set(app.vec, 'Visible', 'on');
            else
                set(app.vec, 'Visible', 'off');
            end           
        end

        % Button pushed function: ForwardKinamaticsButton
        function ForwardKinamaticsButtonPushed(app, event)
            [app.RollEditField.Value,  app.PitchEditField.Value, app.YawEditField.Value] =... 
                 FixedAngleOrientation(app.Theta1EditField.Value * 2*pi/360,...
                                       app.Theta2EditField.Value * 2*pi/360,...
                                       app.Theta3EditField.Value * 2*pi/360,...
                                       app.Theta4EditField.Value * 2*pi/360,...
                                       app.robot_config);
            app.RollEditField.Value  = app.RollEditField.Value  / (2*pi/360);
            app.PitchEditField.Value = app.PitchEditField.Value / (2*pi/360);
            app.YawEditField.Value   = app.YawEditField.Value   / (2*pi/360);

            [pos_joint, A_i_0] =...
                  ForwardKinematics(app.Theta1EditField.Value * 2*pi/360,...
                                    app.Theta2EditField.Value * 2*pi/360,...
                                    app.Theta3EditField.Value * 2*pi/360,...
                                    app.Theta4EditField.Value * 2*pi/360,...
                                    app.robot_config);
            app.pxEditField.Value = pos_joint(6,1);
            app.pyEditField.Value = pos_joint(6,2);
            app.pzEditField.Value = pos_joint(6,3);

            app.theta_B = [app.Theta1EditField.Value * 2*pi/360,... 
                           app.Theta2EditField.Value * 2*pi/360,...
                           app.Theta3EditField.Value * 2*pi/360,...
                           app.Theta4EditField.Value * 2*pi/360];

            app.T10_11EditField.Value = A_i_0{1}(1,1);
            app.T10_12EditField.Value = A_i_0{1}(1,2);
            app.T10_13EditField.Value = A_i_0{1}(1,3);
            app.T10_14EditField.Value = A_i_0{1}(1,4);
            app.T10_21EditField.Value = A_i_0{1}(2,1);
            app.T10_22EditField.Value = A_i_0{1}(2,2);
            app.T10_23EditField.Value = A_i_0{1}(2,3);
            app.T10_24EditField.Value = A_i_0{1}(2,4);
            app.T10_31EditField.Value = A_i_0{1}(3,1);
            app.T10_32EditField.Value = A_i_0{1}(3,2);
            app.T10_33EditField.Value = A_i_0{1}(3,3);
            app.T10_34EditField.Value = A_i_0{1}(3,4);
            app.T10_41EditField.Value = A_i_0{1}(4,1);
            app.T10_42EditField.Value = A_i_0{1}(4,2);
            app.T10_43EditField.Value = A_i_0{1}(4,3);
            app.T10_44EditField.Value = A_i_0{1}(4,4);

            app.T20_11EditField.Value = A_i_0{2}(1,1);
            app.T20_12EditField.Value = A_i_0{2}(1,2);
            app.T20_13EditField.Value = A_i_0{2}(1,3);
            app.T20_14EditField.Value = A_i_0{2}(1,4);
            app.T20_21EditField.Value = A_i_0{2}(2,1);
            app.T20_22EditField.Value = A_i_0{2}(2,2);
            app.T20_23EditField.Value = A_i_0{2}(2,3);
            app.T20_24EditField.Value = A_i_0{2}(2,4);
            app.T20_31EditField.Value = A_i_0{2}(3,1);
            app.T20_32EditField.Value = A_i_0{2}(3,2);
            app.T20_33EditField.Value = A_i_0{2}(3,3);
            app.T20_34EditField.Value = A_i_0{2}(3,4);
            app.T20_41EditField.Value = A_i_0{2}(4,1);
            app.T20_42EditField.Value = A_i_0{2}(4,2);
            app.T20_43EditField.Value = A_i_0{2}(4,3);
            app.T20_44EditField.Value = A_i_0{2}(4,4);

            app.T30_11EditField.Value = A_i_0{4}(1,1);
            app.T30_12EditField.Value = A_i_0{4}(1,2);
            app.T30_14EditField.Value = A_i_0{4}(1,3);
            app.T30_14EditField.Value = A_i_0{4}(1,4);
            app.T30_21EditField.Value = A_i_0{4}(2,1);
            app.T30_22EditField.Value = A_i_0{4}(2,2);
            app.T30_23EditField.Value = A_i_0{4}(2,3);
            app.T30_24EditField.Value = A_i_0{4}(2,4);
            app.T30_31EditField.Value = A_i_0{4}(3,1);
            app.T30_32EditField.Value = A_i_0{4}(3,2);
            app.T30_33EditField.Value = A_i_0{4}(3,3);
            app.T30_34EditField.Value = A_i_0{4}(3,4);
            app.T30_41EditField.Value = A_i_0{4}(4,1);
            app.T30_42EditField.Value = A_i_0{4}(4,2);
            app.T30_43EditField.Value = A_i_0{4}(4,3);
            app.T30_44EditField.Value = A_i_0{4}(4,4);

            app.T40_11EditField.Value = A_i_0{5}(1,1);
            app.T40_12EditField.Value = A_i_0{5}(1,2);
            app.T40_13EditField.Value = A_i_0{5}(1,3);
            app.T40_14EditField.Value = A_i_0{5}(1,4);
            app.T40_21EditField.Value = A_i_0{5}(2,1);
            app.T40_22EditField.Value = A_i_0{5}(2,2);
            app.T40_23EditField.Value = A_i_0{5}(2,3);
            app.T40_24EditField.Value = A_i_0{5}(2,4);
            app.T40_31EditField.Value = A_i_0{5}(3,1);
            app.T40_32EditField.Value = A_i_0{5}(3,2);
            app.T40_33EditField.Value = A_i_0{5}(3,3);
            app.T40_34EditField.Value = A_i_0{5}(3,4);
            app.T40_41EditField.Value = A_i_0{5}(4,1);
            app.T40_42EditField.Value = A_i_0{5}(4,2);
            app.T40_43EditField.Value = A_i_0{5}(4,3);
            app.T40_44EditField.Value = A_i_0{5}(4,4);
             
            if ~isempty(app.model) && isgraphics(app.model)
                delete(app.model);
            end
            delete(app.vec);
           
            handle = AnimateModel(app.theta_A, app.theta_B, app.robot_config, app.model);
            app.model = handle.robot;
            app.vec = handle.vec;

            new_handle_waypoint = handle.waypoint;
            old_handle_robot = app.way_point(isgraphics(app.way_point));
            app.way_point = [old_handle_robot, new_handle_waypoint];
            app.theta_A = app.theta_B;
        end

        % Button pushed function: OperationWorkspaceButton
        function OperationWorkspaceButtonPushed(app, event)
            app.robot_config.pitch = app.PitchEditField.Value * 2*pi/360;
            app.robot_config.workspace = "operation-workspace";
            app.robot_config.vx0 = app.vx0EditField.Value;
            app.robot_config.vy0 = app.vy0EditField.Value;
            app.robot_config.vz0 = app.vz0EditField.Value;

            if isempty(app.WorkSpace_Scatter) || ~isvalid(app.WorkSpace_Scatter)
                app.WorkSpace_Scatter = WorkSpace(app.robot_config);
            else
                delete(app.WorkSpace_Scatter);
                app.WorkSpace_Scatter = [];
            end
        end

        % Button pushed function: ClearWaypointButton
        function ClearWaypointButtonPushed(app, event)
            delete(app.way_point);
            app.way_point = [];
        end

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
            app.robot_config.pitch = app.PitchEditField.Value * 2*pi/360;
            theta_i = InverseKinematics(app.pxEditField.Value, app.pyEditField.Value, app.pzEditField.Value, app.robot_config);
            app.Theta1EditField.Value = theta_i.theta1 / (2*pi/360);
            app.Theta2EditField.Value = theta_i.theta2 / (2*pi/360);
            app.Theta3EditField.Value = theta_i.theta3 / (2*pi/360);
            app.Theta4EditField.Value = theta_i.theta4 / (2*pi/360);
            [pos_joint, A_i_0] =...
                  ForwardKinematics(app.Theta1EditField.Value * 2*pi/360,...
                                    app.Theta2EditField.Value * 2*pi/360,...
                                    app.Theta3EditField.Value * 2*pi/360,...
                                    app.Theta4EditField.Value * 2*pi/360,...
                                    app.robot_config);

            app.theta_B = [app.Theta1EditField.Value * 2*pi/360,... 
                           app.Theta2EditField.Value * 2*pi/360,...
                           app.Theta3EditField.Value * 2*pi/360,...
                           app.Theta4EditField.Value * 2*pi/360];
             
            if ~isempty(app.model) && isgraphics(app.model)
                delete(app.model);
                delete(app.vec);
            end
            delete(app.vec);
            handle = AnimateModel(app.theta_A, app.theta_B, app.robot_config, app.model);
            app.model = handle.robot;
            app.vec = handle.vec;
            new_handle_waypoint = handle.waypoint;
            old_handle_robot = app.way_point(isgraphics(app.way_point));
            app.way_point = [old_handle_robot, new_handle_waypoint];
            app.theta_A = app.theta_B;
        end

        % Button pushed function: EEPlanningButton
        function EEPlanningButtonPushed(app, event)
            app.robot_config.pitch = app.Pitch_AEditField.Value * 2*pi/360;
            px = [app.px_AEditField.Value app.px_BEditField.Value];
            py = [app.py_AEditField.Value app.py_BEditField.Value];
            pz = [app.pz_AEditField.Value app.pz_BEditField.Value];
            delete(app.vec);
            handle = TrajectoryPlanning(px, py, pz, app.robot_config, app.model);
            app.pmaxEditField.Value = handle.pmax;
            app.vmaxEditField.Value = handle.vmax;
            app.amaxEditField.Value = handle.amax;
            app.model = handle.robot;
            app.vec = handle.vec;
            new_handle_waypoint = handle.waypoint;
            old_handle_robot = app.way_point(isgraphics(app.way_point));
            app.way_point = [old_handle_robot, new_handle_waypoint];
            % app.theta_A = app.theta_B;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Theta1EditField.Value = 0;
            app.Theta2EditField.Value = 0;
            app.Theta3EditField.Value = 0;
            app.Theta4EditField.Value = 0;            
            ForwardKinamaticsButtonPushed(app, event);
        end

        % Value changed function: SliderTheta1
        function SliderTheta1ValueChanged(app, event)
            value = app.SliderTheta1.Value;
            app.Theta1EditField.Value = value;
            ForwardKinamaticsButtonPushed(app, event);
        end

        % Value changed function: SliderTheta2
        function SliderTheta2ValueChanged(app, event)
            value = app.SliderTheta2.Value;
            app.Theta2EditField.Value = value;
            ForwardKinamaticsButtonPushed(app, event);
        end

        % Value changed function: SliderTheta3
        function SliderTheta3ValueChanged(app, event)
            value = app.SliderTheta3.Value;
            app.Theta3EditField.Value = value;
            ForwardKinamaticsButtonPushed(app, event);
        end

        % Value changed function: SliderTheta4
        function SliderTheta4ValueChanged(app, event)
            value = app.SliderTheta4.Value;
            app.Theta4EditField.Value = value;
            ForwardKinamaticsButtonPushed(app, event);
        end

        % Button pushed function: WaypointButton
        function WaypointButtonPushed(app, event)
            app.robot_config.workspace = "joint-workspace";
            if isempty(app.WorkSpace_Scatter) || ~isvalid(app.WorkSpace_Scatter)
                app.WorkSpace_Scatter = WorkSpace(app.robot_config);
            else
                delete(app.WorkSpace_Scatter);
                app.WorkSpace_Scatter = [];
            end
        end

        % Value changed function: Link1BlurSpinner
        function Link1BlurSpinnerValueChanged(app, event)
            value = app.Link1BlurSpinner.Value;
            if ~isempty(app.model) && isstruct(app.model)
                if isfield(app.model, 'link11') 
                    link_transform = app.model.link11;
                    myPatch = findobj(link_transform, 'Type', 'patch');                    
                    set(myPatch, 'FaceAlpha', value / 10);
                end
            end
        end

        % Value changed function: Link2BlurSpinner
        function Link2BlurSpinnerValueChanged(app, event)
            value = app.Link2BlurSpinner.Value;
            if isfield(app.model, 'link12')
                link12_transform = app.model.link12;            
                allPatches = findobj(link12_transform, 'Type', 'patch');            
                set(allPatches, 'FaceAlpha', value / 10);
            end
        end

        % Value changed function: Link3BlurSpinner
        function Link3BlurSpinnerValueChanged(app, event)
            value = app.Link3BlurSpinner.Value;
            if isfield(app.model, 'link2')
                link2_transform = app.model.link2;            
                allPatches = findobj(link2_transform, 'Type', 'patch');            
                set(allPatches, 'FaceAlpha', value / 10);
            end
            if isfield(app.model, 'link3')
                link3_transform = app.model.link3;            
                allPatches = findobj(link3_transform, 'Type', 'patch');            
                set(allPatches, 'FaceAlpha', value / 10);
            end
        end

        % Value changed function: Link4BlurSpinner
        function Link4BlurSpinnerValueChanged(app, event)
            value = app.Link4BlurSpinner.Value;
            if isfield(app.model, 'link4')
                link4_transform = app.model.link4;            
                allPatches = findobj(link4_transform, 'Type', 'patch');            
                set(allPatches, 'FaceAlpha', value / 10);
            end
        end

        % Value changed function: ReferenceFrameCheckBox
        function ReferenceFrameCheckBoxValueChanged(app, event)
            value = app.ReferenceFrameCheckBox.Value;            
            if ~isempty(app.vec)
                if value
                    app.robot_config.reference_frame = "on";
                    set(app.vec, 'Visible', 'on');  
                else
                    app.robot_config.reference_frame = "off";
                    set(app.vec, 'Visible', 'off'); 
                end
            end
        end

        % Value changed function: RuntimeSpinner
        function RuntimeSpinnerValueChanged(app, event)
            value = app.RuntimeSpinner.Value;
            app.robot_config.runtime = value;
        end

        % Value changed function: SamplesSpinner
        function SamplesSpinnerValueChanged(app, event)
            value = app.SamplesSpinner.Value;
            app.robot_config.waypoint_n = value;
        end

        % Value changed function: MotionTypesDropDown
        function MotionTypesDropDownValueChanged(app, event)
            value = app.MotionTypesDropDown.Value;
            app.robot_config.motion_type = value;
        end

        % Button pushed function: JointPlanningButton
        function JointPlanningButtonPushed(app, event)
            app.robot_config.pitch = app.Pitch_AEditField.Value * 2*pi/360;

            theta_i = InverseKinematics(app.px_AEditField.Value, app.py_AEditField.Value, app.pz_AEditField.Value, app.robot_config);
            app.Theta1EditField.Value = theta_i.theta1 / (2*pi/360);
            app.Theta2EditField.Value = theta_i.theta2 / (2*pi/360);
            app.Theta3EditField.Value = theta_i.theta3 / (2*pi/360);
            app.Theta4EditField.Value = theta_i.theta4 / (2*pi/360);
           
            app.theta_A = [app.Theta1EditField.Value * 2*pi/360,... 
                           app.Theta2EditField.Value * 2*pi/360,...
                           app.Theta3EditField.Value * 2*pi/360,...
                           app.Theta4EditField.Value * 2*pi/360];

            theta_i = InverseKinematics(app.px_BEditField.Value, app.py_BEditField.Value, app.pz_BEditField.Value, app.robot_config);
            app.Theta1EditField.Value = theta_i.theta1 / (2*pi/360);
            app.Theta2EditField.Value = theta_i.theta2 / (2*pi/360);
            app.Theta3EditField.Value = theta_i.theta3 / (2*pi/360);
            app.Theta4EditField.Value = theta_i.theta4 / (2*pi/360);
           
            app.theta_B = [app.Theta1EditField.Value * 2*pi/360,... 
                           app.Theta2EditField.Value * 2*pi/360,...
                           app.Theta3EditField.Value * 2*pi/360,...
                           app.Theta4EditField.Value * 2*pi/360];
             
            if ~isempty(app.model) && isgraphics(app.model)
                delete(app.model);
            end
            delete(app.vec);
            handle = PathPlanning(app.theta_A, app.theta_B, app.robot_config, app.model);
            app.model = handle.robot;
            app.vec = handle.vec;
            new_handle_waypoint = handle.waypoint;
            old_handle_robot = app.way_point(isgraphics(app.way_point));
            app.way_point = [old_handle_robot, new_handle_waypoint];
            app.theta_A = app.theta_B;        
        end

        % Value changed function: J1UpperLimitEditField
        function J1UpperLimitEditFieldValueChanged(app, event)
            value = app.J1UpperLimitEditField.Value;
            app.robot_config.jointlimit.j1u = value * 2*pi/360;
        end

        % Value changed function: J1LowerLimitEditField
        function J1LowerLimitEditFieldValueChanged(app, event)
            value = app.J1LowerLimitEditField.Value;
            app.robot_config.jointlimit.j1l = value * 2*pi/360;
        end

        % Value changed function: J2UpperLimitEditField
        function J2UpperLimitEditFieldValueChanged(app, event)
            value = app.J2UpperLimitEditField.Value;
            app.robot_config.jointlimit.j2u = value * 2*pi/360;
        end

        % Value changed function: J2LowerLimitEditField
        function J2LowerLimitEditFieldValueChanged(app, event)
            value = app.J2LowerLimitEditField.Value;
            app.robot_config.jointlimit.j2l = value * 2*pi/360;
        end

        % Value changed function: J3UpperLimitEditField
        function J3UpperLimitEditFieldValueChanged(app, event)
            value = app.J3UpperLimitEditField.Value;
            app.robot_config.jointlimit.j3u = value * 2*pi/360;
        end

        % Value changed function: J3LowerLimitEditField
        function J3LowerLimitEditFieldValueChanged(app, event)
            value = app.J3LowerLimitEditField.Value;
            app.robot_config.jointlimit.j3l = value * 2*pi/360;
        end

        % Value changed function: J4UpperLimitEditField
        function J4UpperLimitEditFieldValueChanged(app, event)
            value = app.J4UpperLimitEditField.Value;
            app.robot_config.jointlimit.j4u = value * 2*pi/360;
        end

        % Value changed function: J4LowerLimitEditField
        function J4LowerLimitEditFieldValueChanged(app, event)
            value = app.J4LowerLimitEditField.Value;
            app.robot_config.jointlimit.j4l = value * 2*pi/360;
        end

        % Button pushed function: ClearScreenButton
        function ClearScreenButtonPushed(app, event)
            cla(app.UIAxesPosEE);
            cla(app.UIAxesVelEE);
            cla(app.UIAxesAccEE);
            cla(app.UIAxesPosJoint1);
            cla(app.UIAxesVelJoint1);
            cla(app.UIAxesAccJoint1);
            cla(app.UIAxesPosJoint2);
            cla(app.UIAxesVelJoint2);
            cla(app.UIAxesAccJoint2);
            cla(app.UIAxesPosJoint3);
            cla(app.UIAxesVelJoint3);
            cla(app.UIAxesAccJoint3);
            cla(app.UIAxesPosJoint4);
            cla(app.UIAxesVelJoint4);
            cla(app.UIAxesAccJoint4);
        end

        % Callback function: not associated with a component
        function SingularityButtonPushed(app, event)
            app.t_SEditField.Value = app.robot_config.singularity.t;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Manipulator - X')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ForwardKinematicsPanel = uipanel(app.UIFigure);
            app.ForwardKinematicsPanel.Title = 'Forward Kinematics';
            app.DegLabel = uilabel(app.ForwardKinematicsPanel);
            app.DegLabel.Text = 'Deg';
            app.DegLabel_2 = uilabel(app.ForwardKinematicsPanel);
            app.DegLabel_2.Text = 'Deg';
            app.DegLabel_3 = uilabel(app.ForwardKinematicsPanel);
            app.DegLabel_3.Text = 'Deg';
            app.DegLabel_4 = uilabel(app.ForwardKinematicsPanel);
            app.DegLabel_4.Text = 'Deg';
            app.Theta1EditFieldLabel = uilabel(app.ForwardKinematicsPanel);
            app.Theta1EditFieldLabel.Text = 'Theta1';
            app.Theta1EditField = uieditfield(app.ForwardKinematicsPanel, 'numeric');
            app.Theta2EditFieldLabel = uilabel(app.ForwardKinematicsPanel);
            app.Theta2EditFieldLabel.Text = 'Theta2';
            app.Theta2EditField = uieditfield(app.ForwardKinematicsPanel, 'numeric');
            app.Theta3EditFieldLabel = uilabel(app.ForwardKinematicsPanel);
            app.Theta3EditFieldLabel.Text = 'Theta3';
            app.Theta3EditField = uieditfield(app.ForwardKinematicsPanel, 'numeric');
            app.Theta4EditFieldLabel = uilabel(app.ForwardKinematicsPanel);
            app.Theta4EditFieldLabel.Text = 'Theta4';
            app.Theta4EditField = uieditfield(app.ForwardKinematicsPanel, 'numeric');
            app.SliderTheta1 = uislider(app.ForwardKinematicsPanel);
            app.SliderTheta1.Limits = [0 360];
            app.SliderTheta1.MajorTicks = [];
            app.SliderTheta1.MajorTickLabels = {''};
            app.SliderTheta1.ValueChangedFcn = createCallbackFcn(app, @SliderTheta1ValueChanged, true);
            app.SliderTheta1.MinorTicks = [];
            app.SliderTheta2 = uislider(app.ForwardKinematicsPanel);
            app.SliderTheta2.Limits = [0 360];
            app.SliderTheta2.MajorTicks = [];
            app.SliderTheta2.MajorTickLabels = {''};
            app.SliderTheta2.ValueChangedFcn = createCallbackFcn(app, @SliderTheta2ValueChanged, true);
            app.SliderTheta2.MinorTicks = [];
            app.SliderTheta4 = uislider(app.ForwardKinematicsPanel);
            app.SliderTheta4.Limits = [0 360];
            app.SliderTheta4.MajorTicks = [];
            app.SliderTheta4.MajorTickLabels = {''};
            app.SliderTheta4.ValueChangedFcn = createCallbackFcn(app, @SliderTheta4ValueChanged, true);
            app.SliderTheta4.MinorTicks = [];
            app.SliderTheta3 = uislider(app.ForwardKinematicsPanel);
            app.SliderTheta3.Limits = [0 360];
            app.SliderTheta3.MajorTicks = [];
            app.SliderTheta3.MajorTickLabels = {''};
            app.SliderTheta3.ValueChangedFcn = createCallbackFcn(app, @SliderTheta3ValueChanged, true);
            app.SliderTheta3.MinorTicks = [];
            app.ForwardKinamaticsButton = uibutton(app.ForwardKinematicsPanel, 'push');
            app.ForwardKinamaticsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinamaticsButtonPushed, true);
            app.ForwardKinamaticsButton.Text = {'Forward'; 'Kinamatics'};
            app.ResetButton = uibutton(app.ForwardKinematicsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TabGroup4 = uitabgroup(app.ForwardKinematicsPanel);
            app.T_1_0Tab = uitab(app.TabGroup4);
            app.T_1_0Tab.Title = 'T_1_0';
            app.T10_11EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_12EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_14EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_13EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_21EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_22EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_23EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_24EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_31EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_32EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_33EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_34EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_41EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_42EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_43EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.T10_44EditField = uieditfield(app.T_1_0Tab, 'numeric');
            app.RxLabel_4 = uilabel(app.T_1_0Tab);
            app.RxLabel_4.Text = 'Rx';
            app.RyLabel_4 = uilabel(app.T_1_0Tab);
            app.RyLabel_4.Text = 'Ry';
            app.RzLabel_4 = uilabel(app.T_1_0Tab);
            app.RzLabel_4.Text = 'Rz';
            app.PLabel_4 = uilabel(app.T_1_0Tab);
            app.PLabel_4.Text = 'P';
            app.T_2_0Tab = uitab(app.TabGroup4);
            app.T_2_0Tab.Title = 'T_2_0';
            app.T20_11EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_12EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_14EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_13EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_21EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_22EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_23EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_24EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_31EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_32EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_33EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_34EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_41EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_42EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_43EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.T20_44EditField = uieditfield(app.T_2_0Tab, 'numeric');
            app.RxLabel_3 = uilabel(app.T_2_0Tab);
            app.RxLabel_3.Text = 'Rx';
            app.RyLabel_3 = uilabel(app.T_2_0Tab);
            app.RyLabel_3.Text = 'Ry';
            app.RzLabel_3 = uilabel(app.T_2_0Tab);
            app.RzLabel_3.Text = 'Rz';
            app.PLabel_3 = uilabel(app.T_2_0Tab);
            app.PLabel_3.Text = 'P';
            app.T_3_0Tab = uitab(app.TabGroup4);
            app.T_3_0Tab.Title = 'T_3_0';
            app.T30_11EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_12EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_14EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_13EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_21EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_22EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_23EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_24EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_31EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_32EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_33EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_34EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_41EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_42EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_43EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.T30_44EditField = uieditfield(app.T_3_0Tab, 'numeric');
            app.RxLabel_2 = uilabel(app.T_3_0Tab);
            app.RxLabel_2.Text = 'Rx';
            app.RyLabel_2 = uilabel(app.T_3_0Tab);
            app.RyLabel_2.Text = 'Ry';
            app.RzLabel_2 = uilabel(app.T_3_0Tab);
            app.RzLabel_2.Text = 'Rz';
            app.PLabel_2 = uilabel(app.T_3_0Tab);
            app.PLabel_2.Text = 'P';
            app.T_4_0Tab = uitab(app.TabGroup4);
            app.T_4_0Tab.Title = 'T_4_0';
            app.T40_11EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_12EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_14EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_13EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_21EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_22EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_23EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_24EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_31EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_32EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_33EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_34EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_41EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_42EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_43EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.T40_44EditField = uieditfield(app.T_4_0Tab, 'numeric');
            app.RxLabel = uilabel(app.T_4_0Tab);
            app.RxLabel.Text = 'Rx';
            app.RyLabel = uilabel(app.T_4_0Tab);
            app.RyLabel.Text = 'Ry';
            app.RzLabel = uilabel(app.T_4_0Tab);
            app.RzLabel.Text = 'Rz';
            app.PLabel = uilabel(app.T_4_0Tab);
            app.PLabel.Text = 'P';
            app.DHTab = uitab(app.TabGroup4);
            app.DHTab.Title = 'DH';
            app.T40_11EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_12EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_14EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_13EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_21EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_22EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_23EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_24EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_31EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_32EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_33EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_34EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_41EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_42EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_43EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.T40_44EditField_2 = uieditfield(app.DHTab, 'numeric');
            app.aLabel = uilabel(app.DHTab);
            app.aLabel.Text = 'a';
            app.alphaLabel = uilabel(app.DHTab);
            app.alphaLabel.Text = 'alpha';
            app.dLabel = uilabel(app.DHTab);
            app.dLabel.Text = 'd';
            app.thetaLabel = uilabel(app.DHTab);
            app.thetaLabel.Text = 'theta';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Joint1Tab = uitab(app.TabGroup);
            app.Joint1Tab.Title = 'Joint 1';
            app.UIAxesVelJoint1 = uiaxes(app.Joint1Tab);
            title(app.UIAxesVelJoint1, 'Velocity')
            xlabel(app.UIAxesVelJoint1, 't')
            ylabel(app.UIAxesVelJoint1, 'v')
            zlabel(app.UIAxesVelJoint1, 'Z')
            app.UIAxesAccJoint1 = uiaxes(app.Joint1Tab);
            title(app.UIAxesAccJoint1, 'Acceleration')
            xlabel(app.UIAxesAccJoint1, 't')
            ylabel(app.UIAxesAccJoint1, 'a')
            zlabel(app.UIAxesAccJoint1, 'Z')
            app.UIAxesPosJoint1 = uiaxes(app.Joint1Tab);
            title(app.UIAxesPosJoint1, 'Angle')
            xlabel(app.UIAxesPosJoint1, 't')
            ylabel(app.UIAxesPosJoint1, 'theta')
            zlabel(app.UIAxesPosJoint1, 'Z')
            app.Joint2Tab = uitab(app.TabGroup);
            app.Joint2Tab.Title = 'Joint 2';
            app.UIAxesVelJoint2 = uiaxes(app.Joint2Tab);
            title(app.UIAxesVelJoint2, 'Velocity')
            xlabel(app.UIAxesVelJoint2, 't')
            ylabel(app.UIAxesVelJoint2, 'v')
            zlabel(app.UIAxesVelJoint2, 'Z')
            app.UIAxesAccJoint2 = uiaxes(app.Joint2Tab);
            title(app.UIAxesAccJoint2, 'Acceleration')
            xlabel(app.UIAxesAccJoint2, 't')
            ylabel(app.UIAxesAccJoint2, 'a')
            zlabel(app.UIAxesAccJoint2, 'Z')
            app.UIAxesPosJoint2 = uiaxes(app.Joint2Tab);
            title(app.UIAxesPosJoint2, 'Angle')
            xlabel(app.UIAxesPosJoint2, 't')
            ylabel(app.UIAxesPosJoint2, 'theta')
            zlabel(app.UIAxesPosJoint2, 'Z')
            app.Joint3Tab = uitab(app.TabGroup);
            app.Joint3Tab.Title = 'Joint 3';
            app.UIAxesVelJoint3 = uiaxes(app.Joint3Tab);
            title(app.UIAxesVelJoint3, 'Velocity')
            xlabel(app.UIAxesVelJoint3, 't')
            ylabel(app.UIAxesVelJoint3, 'v')
            zlabel(app.UIAxesVelJoint3, 'Z')
            app.UIAxesAccJoint3 = uiaxes(app.Joint3Tab);
            title(app.UIAxesAccJoint3, 'Acceleration')
            xlabel(app.UIAxesAccJoint3, 't')
            ylabel(app.UIAxesAccJoint3, 'a')
            zlabel(app.UIAxesAccJoint3, 'Z')
            app.UIAxesPosJoint3 = uiaxes(app.Joint3Tab);
            title(app.UIAxesPosJoint3, 'Angle')
            xlabel(app.UIAxesPosJoint3, 't')
            ylabel(app.UIAxesPosJoint3, 'theta')
            zlabel(app.UIAxesPosJoint3, 'Z')
            app.Joint4Tab = uitab(app.TabGroup);
            app.Joint4Tab.Title = 'Joint 4';
            app.UIAxesVelJoint4 = uiaxes(app.Joint4Tab);
            title(app.UIAxesVelJoint4, 'Velocity')
            xlabel(app.UIAxesVelJoint4, 't')
            ylabel(app.UIAxesVelJoint4, 'v')
            zlabel(app.UIAxesVelJoint4, 'Z')
            app.UIAxesAccJoint4 = uiaxes(app.Joint4Tab);
            title(app.UIAxesAccJoint4, 'Acceleration')
            xlabel(app.UIAxesAccJoint4, 't')
            ylabel(app.UIAxesAccJoint4, 'a')
            zlabel(app.UIAxesAccJoint4, 'Z')
            app.UIAxesPosJoint4 = uiaxes(app.Joint4Tab);
            title(app.UIAxesPosJoint4, 'Angle')
            xlabel(app.UIAxesPosJoint4, 't')
            ylabel(app.UIAxesPosJoint4, 'theta')
            zlabel(app.UIAxesPosJoint4, 'Z')
            app.InverseKinematicsPanel = uipanel(app.UIFigure);
            app.InverseKinematicsPanel.Title = 'Inverse Kinematics';
            app.mLabel = uilabel(app.InverseKinematicsPanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.InverseKinematicsPanel);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.InverseKinematicsPanel);
            app.mLabel_3.Text = 'm';
            app.YawEditField = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.YawLabel = uilabel(app.InverseKinematicsPanel);
            app.YawLabel.Text = 'Yaw';
            app.DegLabel_5 = uilabel(app.InverseKinematicsPanel);
            app.DegLabel_5.Text = 'Deg';
            app.DegLabel_6 = uilabel(app.InverseKinematicsPanel);
            app.DegLabel_6.Text = 'Deg';
            app.DegLabel_7 = uilabel(app.InverseKinematicsPanel);
            app.DegLabel_7.Text = 'Deg';
            app.pxEditFieldLabel = uilabel(app.InverseKinematicsPanel);
            app.pxEditFieldLabel.Text = 'px';
            app.pxEditField = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.pyEditFieldLabel = uilabel(app.InverseKinematicsPanel);
            app.pyEditFieldLabel.Text = 'py';
            app.pyEditField = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.pzEditFieldLabel = uilabel(app.InverseKinematicsPanel);
            app.pzEditFieldLabel.Text = 'pz';
            app.pzEditField = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.RollEditFieldLabel = uilabel(app.InverseKinematicsPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.PitchLabel = uilabel(app.InverseKinematicsPanel);
            app.PitchLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.InverseKinematicsButton = uibutton(app.InverseKinematicsPanel, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Text = {'Inverse'; 'Kinematics'};
            app.ClearWaypointButton = uibutton(app.InverseKinematicsPanel, 'push');
            app.ClearWaypointButton.ButtonPushedFcn = createCallbackFcn(app, @ClearWaypointButtonPushed, true);
            app.ClearWaypointButton.Text = {'Clear '; 'Waypoint'};
            app.TabGroup3 = uitabgroup(app.InverseKinematicsPanel);
            app.J1Tab = uitab(app.TabGroup3);
            app.J1Tab.Title = 'J1';
            app.UpperLimitEditFieldLabel = uilabel(app.J1Tab);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.J1UpperLimitEditField = uieditfield(app.J1Tab, 'numeric');
            app.J1UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @J1UpperLimitEditFieldValueChanged, true);
            app.LowerLimitEditFieldLabel = uilabel(app.J1Tab);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.J1LowerLimitEditField = uieditfield(app.J1Tab, 'numeric');
            app.J1LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @J1LowerLimitEditFieldValueChanged, true);
            app.J2Tab = uitab(app.TabGroup3);
            app.J2Tab.Title = 'J2';
            app.UpperLimitEditField_2Label = uilabel(app.J2Tab);
            app.UpperLimitEditField_2Label.Text = 'Upper Limit';
            app.J2UpperLimitEditField = uieditfield(app.J2Tab, 'numeric');
            app.J2UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @J2UpperLimitEditFieldValueChanged, true);
            app.LowerLimitEditField_2Label = uilabel(app.J2Tab);
            app.LowerLimitEditField_2Label.Text = 'Lower Limit';
            app.J2LowerLimitEditField = uieditfield(app.J2Tab, 'numeric');
            app.J2LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @J2LowerLimitEditFieldValueChanged, true);
            app.J3Tab = uitab(app.TabGroup3);
            app.J3Tab.Title = 'J3';
            app.UpperLimitEditField_3Label = uilabel(app.J3Tab);
            app.UpperLimitEditField_3Label.Text = 'Upper Limit';
            app.J3UpperLimitEditField = uieditfield(app.J3Tab, 'numeric');
            app.J3UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @J3UpperLimitEditFieldValueChanged, true);
            app.LowerLimitEditField_3Label = uilabel(app.J3Tab);
            app.LowerLimitEditField_3Label.Text = 'Lower Limit';
            app.J3LowerLimitEditField = uieditfield(app.J3Tab, 'numeric');
            app.J3LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @J3LowerLimitEditFieldValueChanged, true);
            app.J4Tab = uitab(app.TabGroup3);
            app.J4Tab.Title = 'J4';
            app.UpperLimitEditField_4Label = uilabel(app.J4Tab);
            app.UpperLimitEditField_4Label.Text = 'Upper Limit';
            app.J4UpperLimitEditField = uieditfield(app.J4Tab, 'numeric');
            app.J4UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @J4UpperLimitEditFieldValueChanged, true);
            app.LowerLimitEditField_4Label = uilabel(app.J4Tab);
            app.LowerLimitEditField_4Label.Text = 'Lower Limit';
            app.J4LowerLimitEditField = uieditfield(app.J4Tab, 'numeric');
            app.J4LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @J4LowerLimitEditFieldValueChanged, true);
            app.TrajectoryPlanningPanel = uipanel(app.UIFigure);
            app.TrajectoryPlanningPanel.Title = 'Trajectory Planning';
            app.px_AEditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.px_AEditFieldLabel.Text = 'px_A';
            app.px_AEditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.py_AEditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.py_AEditFieldLabel.Text = 'py_A';
            app.py_AEditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.pz_AEditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.pz_AEditFieldLabel.Text = 'pz_A';
            app.pz_AEditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.Pitch_AEditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.Pitch_AEditFieldLabel.Text = 'Pitch_A';
            app.Pitch_AEditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.px_BEditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.px_BEditFieldLabel.Text = 'px_B';
            app.px_BEditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.py_BEditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.py_BEditFieldLabel.Text = 'py_B';
            app.py_BEditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.pz_BEditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.pz_BEditFieldLabel.Text = 'pz_B';
            app.pz_BEditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.Pitch_BEditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.Pitch_BEditFieldLabel.Text = 'Pitch_B';
            app.Pitch_BEditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.OperationWorkspaceButton = uibutton(app.TrajectoryPlanningPanel, 'push');
            app.OperationWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @OperationWorkspaceButtonPushed, true);
            app.OperationWorkspaceButton.Text = {'Operation '; 'Workspace'};
            app.EEPlanningButton = uibutton(app.TrajectoryPlanningPanel, 'push');
            app.EEPlanningButton.ButtonPushedFcn = createCallbackFcn(app, @EEPlanningButtonPushed, true);
            app.EEPlanningButton.Text = {'E.E'; 'Planning'};
            app.WaypointButton = uibutton(app.TrajectoryPlanningPanel, 'push');
            app.WaypointButton.ButtonPushedFcn = createCallbackFcn(app, @WaypointButtonPushed, true);
            app.WaypointButton.Text = 'Waypoint';
            app.MotionTypesDropDownLabel = uilabel(app.TrajectoryPlanningPanel);
            app.MotionTypesDropDownLabel.Text = 'Motion Types';
            app.MotionTypesDropDown = uidropdown(app.TrajectoryPlanningPanel);
            app.MotionTypesDropDown.Items = {'', 'Cubic', 'Quintic'};
            app.MotionTypesDropDown.ValueChangedFcn = createCallbackFcn(app, @MotionTypesDropDownValueChanged, true);
            app.MotionTypesDropDown.Value = '';
            app.JointPlanningButton = uibutton(app.TrajectoryPlanningPanel, 'push');
            app.JointPlanningButton.ButtonPushedFcn = createCallbackFcn(app, @JointPlanningButtonPushed, true);
            app.JointPlanningButton.Text = {'Joint'; 'Planning'};
            app.vx0EditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.vx0EditFieldLabel.Text = 'vx0';
            app.vx0EditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.vy0EditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.vy0EditFieldLabel.Text = 'vy0';
            app.vy0EditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.vz0EditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.vz0EditFieldLabel.Text = 'vz0';
            app.vz0EditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.ax0EditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.ax0EditFieldLabel.Text = 'ax0';
            app.ax0EditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.ay0EditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.ay0EditFieldLabel.Text = 'ay0';
            app.ay0EditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.az0EditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.az0EditFieldLabel.Text = 'az0';
            app.az0EditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.pmaxEditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.pmaxEditFieldLabel.Text = 'pmax';
            app.pmaxEditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.vmaxEditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.vmaxEditFieldLabel.Text = 'vmax';
            app.vmaxEditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.amaxEditFieldLabel = uilabel(app.TrajectoryPlanningPanel);
            app.amaxEditFieldLabel.Text = 'amax';
            app.amaxEditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.t_SEditField = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
            app.amaxEditFieldLabel_2 = uilabel(app.TrajectoryPlanningPanel);
            app.amaxEditFieldLabel_2.Text = 't_S';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.ConfigurationTab = uitab(app.TabGroup2);
            app.ConfigurationTab.Title = 'Configuration';
            app.RuntimeSpinnerLabel = uilabel(app.ConfigurationTab);
            app.RuntimeSpinnerLabel.Text = 'Run time';
            app.RuntimeSpinner = uispinner(app.ConfigurationTab);
            app.RuntimeSpinner.ValueChangedFcn = createCallbackFcn(app, @RuntimeSpinnerValueChanged, true);
            app.Link1BlurSpinnerLabel = uilabel(app.ConfigurationTab);
            app.Link1BlurSpinnerLabel.Text = 'Link 1 Blur';
            app.Link1BlurSpinner = uispinner(app.ConfigurationTab);
            app.Link1BlurSpinner.ValueChangedFcn = createCallbackFcn(app, @Link1BlurSpinnerValueChanged, true);
            app.Link2BlurSpinnerLabel = uilabel(app.ConfigurationTab);
            app.Link2BlurSpinnerLabel.Text = 'Link 2 Blur';
            app.Link2BlurSpinner = uispinner(app.ConfigurationTab);
            app.Link2BlurSpinner.ValueChangedFcn = createCallbackFcn(app, @Link2BlurSpinnerValueChanged, true);
            app.Link3BlurSpinner = uispinner(app.ConfigurationTab);
            app.Link3BlurSpinner.ValueChangedFcn = createCallbackFcn(app, @Link3BlurSpinnerValueChanged, true);
            app.Link4BlurSpinner = uispinner(app.ConfigurationTab);
            app.Link4BlurSpinner.ValueChangedFcn = createCallbackFcn(app, @Link4BlurSpinnerValueChanged, true);
            app.Link1BlurSpinnerLabel_2 = uilabel(app.ConfigurationTab);
            app.Link1BlurSpinnerLabel_2.Text = 'Link 3 Blur';
            app.Link2BlurSpinnerLabel_2 = uilabel(app.ConfigurationTab);
            app.Link2BlurSpinnerLabel_2.Text = 'Link 4 Blur';
            app.SamplesSpinnerLabel = uilabel(app.ConfigurationTab);
            app.SamplesSpinnerLabel.Text = 'Samples';
            app.SamplesSpinner = uispinner(app.ConfigurationTab);
            app.SamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @SamplesSpinnerValueChanged, true);
            app.ReferenceFrameCheckBox = uicheckbox(app.ConfigurationTab);
            app.ReferenceFrameCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReferenceFrameCheckBoxValueChanged, true);
            app.ReferenceFrameCheckBox.Text = 'Reference Frame';
            app.Link4BlurSpinner_2 = uispinner(app.ConfigurationTab);
            app.Link3BlurSpinner_2 = uispinner(app.ConfigurationTab);
            app.Length2SpinnerLabel = uilabel(app.ConfigurationTab);
            app.Length2SpinnerLabel.Text = 'Length 2';
            app.Length2Spinner = uispinner(app.ConfigurationTab);
            app.Link2BlurSpinnerLabel_3 = uilabel(app.ConfigurationTab);
            app.Link2BlurSpinnerLabel_3.Text = 'Length 4';
            app.Link1BlurSpinnerLabel_3 = uilabel(app.ConfigurationTab);
            app.Link1BlurSpinnerLabel_3.Text = 'Length 3';
            app.Length1SpinnerLabel = uilabel(app.ConfigurationTab);
            app.Length1SpinnerLabel.Text = 'Length 1';
            app.Length1Spinner = uispinner(app.ConfigurationTab);
            app.Link2BlurSpinnerLabel_4 = uilabel(app.ConfigurationTab);
            app.Link2BlurSpinnerLabel_4.Text = 'Length 5';
            app.Link4BlurSpinner_3 = uispinner(app.ConfigurationTab);
            app.ClearScreenButton = uibutton(app.ConfigurationTab, 'push');
            app.ClearScreenButton.ButtonPushedFcn = createCallbackFcn(app, @ClearScreenButtonPushed, true);
            app.ClearScreenButton.Text = {'Clear'; 'Screen'};
            app.PVATab = uitab(app.TabGroup2);
            app.PVATab.Title = 'PVA';
            app.UIAxesPosEE = uiaxes(app.PVATab);
            title(app.UIAxesPosEE, 'E.E Position')
            xlabel(app.UIAxesPosEE, 'X')
            ylabel(app.UIAxesPosEE, 'Y')
            zlabel(app.UIAxesPosEE, 'Z')
            app.UIAxesVelEE = uiaxes(app.PVATab);
            title(app.UIAxesVelEE, 'E.E Velocity')
            xlabel(app.UIAxesVelEE, 'X')
            ylabel(app.UIAxesVelEE, 'Y')
            zlabel(app.UIAxesVelEE, 'Z')
            app.UIAxesAccEE = uiaxes(app.PVATab);
            title(app.UIAxesAccEE, 'E.E Acceleration')
            xlabel(app.UIAxesAccEE, 'X')
            ylabel(app.UIAxesAccEE, 'Y')
            zlabel(app.UIAxesAccEE, 'Z')
            app.DesignedbyTheEngineerLabel = uilabel(app.UIFigure);
            app.DesignedbyTheEngineerLabel.Text = 'Designed by The Engineer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end